classdef hyperspectral_pls_system < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        UIAxes2                 matlab.ui.control.UIAxes
        LeftButton              matlab.ui.control.Button
        RightButton             matlab.ui.control.Button
        Status                  matlab.ui.control.TextArea
        TabGroup                matlab.ui.container.TabGroup
        SpecimDataTab           matlab.ui.container.Tab
        StripROIPanel           matlab.ui.container.Panel
        PositionLabel           matlab.ui.control.Label
        PositionSlider          matlab.ui.control.Slider
        VerticalCheckBox        matlab.ui.control.CheckBox
        WidthSpinnerLabel       matlab.ui.control.Label
        WidthSpinner            matlab.ui.control.Spinner
        RegionofInterestPanel   matlab.ui.container.Panel
        FullImageCheckBox       matlab.ui.control.CheckBox
        EdgeFinderCheckBox      matlab.ui.control.CheckBox
        StripCheckBox           matlab.ui.control.CheckBox
        LoadSamplesButton       matlab.ui.control.Button
        ExportButton            matlab.ui.control.Button
        ViewSampleButton        matlab.ui.control.Button
        CalibrationTab          matlab.ui.container.Tab
        XDataPanel              matlab.ui.container.Panel
        ViewXButton             matlab.ui.control.Button
        LoadXButton             matlab.ui.control.Button
        Label                   matlab.ui.control.Label
        DerivDegreeSpinner      matlab.ui.control.Spinner
        DerivativeButton        matlab.ui.control.Button
        YDataPanel              matlab.ui.container.Panel
        LoadYButton             matlab.ui.control.Button
        ViewYButton             matlab.ui.control.Button
        ModelCalibrationPanel   matlab.ui.container.Panel
        ComponentsSpinnerLabel  matlab.ui.control.Label
        ComponentsSpinner       matlab.ui.control.Spinner
        CVFoldsSpinnerLabel     matlab.ui.control.Label
        CVFoldsSpinner          matlab.ui.control.Spinner
        CalibrateButton         matlab.ui.control.Button
        PlotDropDownLabel       matlab.ui.control.Label
        PlotDropDown            matlab.ui.control.DropDown
        UITable                 matlab.ui.control.Table
        PredictionTab           matlab.ui.container.Tab
        UIAxes                  matlab.ui.control.UIAxes
    end

    properties (Access = private)
        %samples structure has the following fields:
            % .raw holds normalized hyperspectral data
            % .im holds an image from 3 wavelengths to be displayed
            % .spectrum holds average spectrum for sample
            %EDGE FINDER: .masked holds masked hyperspec data for collecting avg spectra
            %EDGE FINDER: .boundary holds the polygon bounding roi
            %STRIP: .box holds polygon bounding roi            
        samples;
        curr_sample = 1;
        
        %holds pls data
        x;
        y;
        yfit;
        PctVar;
        beta;
        residuals;
        mse;
        stats;
        
        %flags
        vertical = false;
        strip = false;
        autoroi = false; 
        
        %pls flags
        var_explained = 0;
        residuals_plot = 0;
        fitted = 1;
    end
    
    methods (Access = private)
        function [HS,Dref,Wref,wavelength] = HS_SpecImport(app, PathName)
            %Read in Hyperspectral data from SPECIM
            
            %  DEPENDENCIES: -ENVI file reader/writer --> enviread()
            
            % Marja Haagsma - marja_haagsma@hotmail.com
            % November 2018
            
            %% Identify files to be read in directory (contains DARKREF, DATA, WHITEREF)
            List=dir(PathName);
            List(1:2,:)=[];
            
            %Create list for Darkref:
            ii=1;   %counter in case there are more than 1 raw data files
            iii=1;  %counter in case there are more than 1 raw data files
            for i=1:length(List)
                if strncmp(List(i).name,'DARK',4)
                    if ~isempty(strfind(List(i).name,'raw'))
                    DarkNameRaw=List(i).name;
                    elseif ~isempty(strfind(List(i).name,'hdr'))
                    DarkNameHeader=List(i).name;
                    end
                elseif strncmp(List(i).name,'WHITE',5)
                    if ~isempty(strfind(List(i).name,'raw'))
                    WhiteNameRaw=List(i).name;
                    elseif ~isempty(strfind(List(i).name,'hdr'))
                    WhiteNameHeader=List(i).name;
                    end
                else
                    if ~isempty(strfind(List(i).name,'raw'))
                    FileNameRaw=List(i).name;
                    ii=ii+1;
                    elseif ~isempty(strfind(List(i).name,'hdr'))
                    FileNameHeader=List(i).name;
                    iii=iii+1;
                    end
                end
            end
            
            
            [HS,info]=enviread([PathName,FileNameRaw],[PathName,FileNameHeader]);
            [Dref,Dinfo]=enviread([PathName,DarkNameRaw],[PathName,DarkNameHeader]);
            [Wref,Winfo]=enviread([PathName,WhiteNameRaw],[PathName,WhiteNameHeader]);
            
            wv=info.Wavelength;
            wv=wv(2:end-1);
            wavelength=strsplit(wv,',');
            wavelength=str2double(wavelength);
        end
        
        %load data into samples structure. i specifies index.
        function loaddata_specim(app, path, i)          
            %data import and normalization
            [HS,Dref,Wref,wavelength] = HS_SpecImport(app, strcat(path,'\capture\'));
            wr_av = mean(Dref); % calc mean dark reference reflectance
            dr_av = mean(Wref); % calc mean white reference reflectance
            app.samples(i).raw = (HS - dr_av) ./ (wr_av - dr_av);
            
            %cropping out edges - MOVE TO displaysample
            app.samples(i).raw = app.samples(i).raw(:,30:end-30,:);
            
            %create image for sample
            app.samples(i).im = app.samples(i).raw(:,:,[1,100,200]);
            
            %do edge finding on sample
            if (app.autoroi)
                findboundary(app, i);
            end
        end
        
        function findboundary(app, i)
            %create mask
            I = app.samples(i).raw(:,:,1);
            mask = zeros(size(I));
            mask(25:end-25,25:end-25) = 1;
            mask = activecontour(I,mask,300);
            app.samples(i).masked = app.samples(i).raw .* mask;
        
            %create boundary
            app.samples(i).boundary = bwboundaries(mask);
        end
        
        %display current sample, roi, and avg spectrum
        function displaysample(app, i)
            %clear axes and display image 
            cla(app.UIAxes)
            app.UIAxes.Title.String = '';
            app.UIAxes.XLabel.String = '';
            app.UIAxes.YLabel.String = '';
            imshow(app.samples(i).im,'Parent',app.UIAxes);
            hold(app.UIAxes,'on')
            
            %Display ROI
            if (app.autoroi)
                %plot roi
                for k = 1:length(app.samples(i).boundary)
                    boundary = app.samples(i).boundary{k};
                    plot(app.UIAxes, boundary(:,2), boundary(:,1),'r','LineWidth', 1.5);
                end
            elseif (app.strip)
                %draw box
                drawpoly(app, app.samples(i).box);
            end
            
            %Plot spectrum
            cla(app.UIAxes2);   %clear plot           
            plot(app.UIAxes2, app.samples(i).spectrum, 'r');
            %scale axes for wavelengths
            xt=arrayfun(@num2str,uint16((get(app.UIAxes2,'xtick')*3.5045) + 935),'un',0);
            
            app.UIAxes2.YLim = [-inf,inf];
            app.UIAxes2.XTickLabelMode = 'manual';
            app.UIAxes2.XTickLabel = xt;
            app.UIAxes2.Title.String = 'Average Spectrum';
            app.UIAxes2.YLabel.String = 'Reflectance';
            app.UIAxes2.XLabel.String = 'Wavelength (nm)';
        end
        
        %create avg spectrum for sample i. 
        %can use box, edgefinder, or entire image
        function getavgspec(app, i)
            [rows, cols] = size(app.samples(i).raw(:,:,1)); %get image size
            
            %calc spectrum for:           
            %edgefinder
            if (app.autoroi)              
                %create masked avg spectrum - much faster than looping but
                %I don't understand how this works
                sample_list = reshape(app.samples(i).masked, [rows*cols,224]);
                sample_list = sample_list';
                [ii,~,v] = find(sample_list);
                app.samples(i).spectrum = accumarray(ii,v,[],@mean)';
            end
            
            %strip
            if (app.strip)
                %draw boxes and get spectra
                width = app.WidthSpinner.Value;
                scale = app.PositionSlider.Value;           
                app.samples(i).spectrum = zeros(1,224);
                
                %min/max scale depends on width
    
                %build box
                if(app.VerticalCheckBox.Value)
                    leftx = cols*scale - width/2;
                    rightx = cols*scale + width/2;
                    
                    if(leftx < 1)
                        leftx = 1;
                    end
                    if(rightx > cols)
                        rightx = cols;
                    end
                    
                    box = [leftx, rightx, rightx, leftx;
                           0, 0, rows, rows];
                else
                    bottomy = rows*scale - width/2;
                    topy = rows*scale + width/2;
                    
                    if(bottomy < 1)
                        bottomy = 1;
                    end
                    if(topy > rows)
                        topy = rows;
                    end
                    
                    box = [0, 0, cols, cols; 
                           bottomy, topy, topy, bottomy];
                end
                app.samples(i).box = box;
                
                %get avg spectrum inside box
                box(box == 0) = 1;
                box = uint16(box);
                sample_list = app.samples(i).raw(box(2,1):box(2,3),box(1,1):box(1,3),:);
                [brows,bcols] = size(sample_list(:,:,1));
                sample_list = reshape(sample_list, [brows*bcols,224]);
                app.samples(i).spectrum = mean(sample_list);
            end
            
            %entire image
            if (~app.autoroi && ~app.strip)
                %create full avg spectrum
                sample_list = reshape(app.samples(i).raw, [rows*cols,224]);
                app.samples(i).spectrum = mean(sample_list);
            end
            
            %setup avg spectra for use as pls x variable
            app.x = zeros(length(app.samples),length(app.samples(1).spectrum));
            for i = 1:length(app.samples)
                app.x(i,:) = app.samples(i).spectrum;
            end
        end
        
        %drawpolygon() function does not support uiaxes object
        %takes a 2 x n matrix of the form [x1 x2 x3; y1 y2 y3]
        function drawpoly(app, poly)
            n = size(poly);
            n = n(2);
            
            for i = 1:n-1               
                line(app.UIAxes,poly(1,i:i+1), poly(2,i:i+1))
            end
            
            line(app.UIAxes,[poly(1,n), poly(1,1)], [poly(2,n), poly(2,1)])
        end
        
        %display x data for pls
        function displayx(app)
            cla(app.UIAxes2)
            hold(app.UIAxes2,'on')
            
            app.UIAxes2.XTickLabelMode = 'auto';
            app.UIAxes2.Title.String = 'X Data';
            app.UIAxes2.YLabel.String = '';
            app.UIAxes2.XLabel.String = 'Sample';
            
            for i = 1:size(app.x,1)
                plot(app.UIAxes2, app.x(i,:));
            end
            
            app.UIAxes2.YLim = [-inf inf];
            app.UIAxes2.XLim = [-inf inf];
        end
        
        %display y data for pls
        function displayy(app)
        
            cla(app.UIAxes2)
            hold(app.UIAxes2,'off')
            
            app.UIAxes2.XTickLabelMode = 'auto';
            app.UIAxes2.Title.String = 'Y Data';
            app.UIAxes2.YLabel.String = '';
            app.UIAxes2.XLabel.String = 'Sample';           
            
            bar(app.UIAxes2,app.y')
            
            app.UIAxes2.YLim = [-inf inf];
            app.UIAxes2.XLim = [-inf inf];
        end
        
        %displays plots of our pls model
        function displayplots(app, plotType)
            cla(app.UIAxes,'reset')
            app.UIAxes.XTickMode = 'auto';
            app.UIAxes.YTickMode = 'auto';
            
            switch plotType
                case '% Var Explained'
                    %plot variance explained per component
                    plot(app.UIAxes, 1:length(app.PctVar), cumsum(100*app.PctVar(2,:)),'-bo');
                    app.UIAxes.Title.String = 'Y Variance Explained Per Component';
                    app.UIAxes.XLabel.String = 'Number of PLS Components';
                    app.UIAxes.YLabel.String = 'Percent Variance Explained in Y';  
                    
                case 'Observed vs. Fitted'
                    %plot predicted vs observed moe for calibration set           
                    plot(app.UIAxes, app.y, app.yfit,'o');
                    
                    hold(app.UIAxes,'on');
                    app.UIAxes.Title.String = 'Predicted vs Fitted Y Values';
                    app.UIAxes.XLabel.String = 'Observed Y';
                    app.UIAxes.YLabel.String = 'Fitted Y';
                    
                    %plot line of best fit
                    p = polyfit(app.y, app.yfit,1);
                    xp = min(app.y):0.1:max(app.y);
                    yp = polyval(p,xp);
                    plot(app.UIAxes,xp,yp);
                    
                case 'Residuals'
                    %plot residuals
                    hold(app.UIAxes,'on');
                    plot(app.UIAxes,app.residuals,'o');
                    plot(app.UIAxes,[0,length(app.residuals)],[0,0]);
                    app.UIAxes.Title.String = 'Residuals';
                    
                case 'MSE'
                    %plot  for predictor and response data
                    yyaxis(app.UIAxes,'left');
                    plot(app.UIAxes,0:length(app.PctVar),app.mse(1,:),'-bo','Color','b');
                    
                    yyaxis(app.UIAxes,'right');
                    plot(app.UIAxes,0:length(app.PctVar),app.mse(2,:),'-bo','Color','r');
                    
                    legend(app.UIAxes,'MSE Predictors','MSE Response');
                    app.UIAxes.XLabel.String = 'Number of Components';
                   
                case 'X Data Weights'
                    %plot weight of diff frequencies
                    plot(app.UIAxes,1:size(app.x,2),app.stats.W,'-');
                    app.UIAxes.Title.String = 'Weighting of Wavelengths for all Components';
                    app.UIAxes.XLabel.String = 'Wavelength';
                    app.UIAxes.YLabel.String = 'PLS Weight';
            end
        end
    end   
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %default test data 
            defaultData = 'C:\Users\Asa\Desktop\Wood Science\Data\Asa_Knots_1_2019-06-04_17-54-49';
            loaddata_specim(app, defaultData, 1);
            getavgspec(app, 1);
            displaysample(app, 1);
        end

        % Button pushed function: LoadSamplesButton
        function LoadSamplesButtonPushed(app, event)
            % Display uigetdir dialog
            paths = uigetfile_n_dir();
            
            % Make sure user didn't cancel uigetfile dialog
            if (~isempty(paths))
                for i = 1:length(paths)
                    app.Status.Value = ['Loading file ', num2str(i), ' of ', num2str(length(paths))];
                    pause(0.01)
                    loaddata_specim(app, paths{i}, i);
                    getavgspec(app,i);
                end
                app.Status.Value = 'Load complete.';
                
                displaysample(app, 1);
            end            
        end

        % Button pushed function: ExportButton
        function ExportButtonPushed(app, event)
            [name,path] = uiputfile('avg_spectra.txt');
            path = strcat(path,name);
            dlmwrite(path, app.samples(1).spectrum,'delimiter',',');
            for i = 2:length(app.samples)
                dlmwrite(path, app.samples(i).spectrum,'-append','delimiter',',');
            end
            app.Status.Value = 'Bulk export complete.';       
        end

        % Value changed function: VerticalCheckBox
        function VerticalCheckBoxValueChanged(app, event)
            for i = 1:length(app.samples)
                getavgspec(app, i);
            end
            displaysample(app, app.curr_sample);
        end

        % Value changed function: PositionSlider
        function PositionSliderValueChanged(app, event)
            for i = 1:length(app.samples)
                getavgspec(app, i);
            end
            displaysample(app, app.curr_sample);
        end

        % Value changed function: WidthSpinner
        function WidthSpinnerValueChanged(app, event)
            for i = 1:length(app.samples)
                getavgspec(app, i);
            end
            displaysample(app, app.curr_sample);
        end

        % Button pushed function: LeftButton
        function LeftButtonPushed(app, event)
            app.curr_sample = app.curr_sample - 1;
            if (app.curr_sample == 0)
                app.curr_sample = length(app.samples);
            end

            displaysample(app, app.curr_sample);
        end

        % Button pushed function: RightButton
        function RightButtonPushed(app, event)
                app.curr_sample = app.curr_sample + 1;
                if (app.curr_sample > length(app.samples))
                    app.curr_sample = 1;
                end

                displaysample(app, app.curr_sample);   
        end

        % Value changed function: StripCheckBox
        function StripCheckBoxValueChanged(app, event)
            value = app.StripCheckBox.Value;
            if (value == 1)
                app.EdgeFinderCheckBox.Value = 0;
                app.FullImageCheckBox.Value = 0;
                %flagging
                app.strip = value;
                app.autoroi = 0;
            else
                app.StripCheckBox.Value = 1;
            end
            
            for i = 1:length(app.samples)
                getavgspec(app,i);
            end
            
            displaysample(app, app.curr_sample);     
        end

        % Value changed function: EdgeFinderCheckBox
        function EdgeFinderCheckBoxValueChanged(app, event)
            value = app.EdgeFinderCheckBox.Value;
            if (value == 1)
                app.StripCheckBox.Value = 0;
                app.FullImageCheckBox.Value = 0;
                %flagging
                app.autoroi = 1;
                app.strip = 0;
            else
                app.EdgeFinderCheckBox.Value = 1;
            end
            
            for i = 1:length(app.samples)
                app.Status.Value = ['Calculating edges (',num2str(i),' of ',num2str(length(app.samples)),')'];
                pause(0.01);   
                findboundary(app,i);
                getavgspec(app,i);
            end
            
            app.Status.Value = 'Edge finder finished.';
            displaysample(app, app.curr_sample); 
        end

        % Value changed function: FullImageCheckBox
        function FullImageCheckBoxValueChanged(app, event)
            value = app.FullImageCheckBox.Value;
            if (value == 1)
                app.StripCheckBox.Value = 0;
                app.EdgeFinderCheckBox.Value = 0;
                app.autoroi = 0;
                app.strip = 0;
            else
                app.FullImageCheckBox.Value = 1;
            end
            
            for i = 1:length(app.samples)
                getavgspec(app,i);
            end
            
            displaysample(app, app.curr_sample);
        end

        % Button pushed function: ViewSampleButton
        function ViewSampleButtonPushed(app, event)
            displaysample(app, app.curr_sample);
        end

        % Button pushed function: ViewXButton
        function ViewXButtonPushed(app, event)
            if(~isempty(app.x) > 0)
                displayx(app);
            end
        end

        % Button pushed function: DerivativeButton
        function DerivativeButtonPushed(app, event)
            degree = app.DerivDegreeSpinner.Value;
            
            %take derivative of x-data using savitsky-golay method
            [x_filt,dif_filt] = sgolay(degree,9);
            x_deriv = zeros(size(app.x));
            stepsize = 1;
            for i = 1:size(app.x,1)
                x_deriv(i,:) = conv(app.x(i,:), factorial(degree)/(-stepsize)^degree * dif_filt(:,degree+1), 'same');
            end
            app.x = x_deriv;
            
            app.x = app.x(:,5:end-4);
            displayx(app);
        end

        % Button pushed function: LoadXButton
        function LoadXButtonPushed(app, event)
            [file,path] = uigetfile('*.txt');
            if (ischar(path))
                path = strcat(path,file);
                app.x = readmatrix(path);
            end
            displayx(app);
        end

        % Button pushed function: LoadYButton
        function LoadYButtonPushed(app, event)
            [file,path] = uigetfile('*.txt');            
            if (ischar(path))
                path = strcat(path,file);
                app.y = readmatrix(path);
            end
            displayy(app);
        end

        % Button pushed function: ViewYButton
        function ViewYButtonPushed(app, event)
            if(~isempty(app.y) > 0)
                displayy(app);
            end
        end

        % Button pushed function: CalibrateButton
        function CalibrateButtonPushed(app, event)
            %run pls regression
            num_components = app.ComponentsSpinner.Value;
            cv_folds = app.CVFoldsSpinner.Value;
            disp(['Calibrating PLS model with ',num2str(num_components),' components.'])

            [Xloadings,Yloadings,Xscores,Yscores,beta,PctVar,mse,stats] = plsregress(app.x,app.y,num_components,'cv',cv_folds);
            app.beta = beta;
            app.PctVar = PctVar;
            app.mse = mse;
            app.stats = stats;
            
            %calc fitted y values and residuals
            app.yfit = [ones(size(app.x,1),1) app.x] * beta;
            app.residuals = app.y - app.yfit;
            
            %calc r2
            TSS = sum((app.y-mean(app.y)).^2);
            RSS = sum((app.y-app.yfit).^2);
            R2 = 1 - RSS/TSS;            
            
            %calc RMSEC, RMSECV, RMSEP
            RMSEC = sqrt(sum((app.yfit - app.y).^2)/(size(app.x,1) - num_components));
            RMSECV = sqrt(sum((app.yfit - app.y).^2)/(size(app.x,1) - 1));
            %RMSEP = sqrt(sum((app.yfit - app.y).^2)/(size(app.x,1) - 1));
            
            %update table
            app.UITable.Data = [length(app.y),round(R2,2),round(RMSEC,2),round(RMSECV,2)];

            %display plot
            displayplots(app, app.PlotDropDown.Value);
        end

        % Value changed function: PlotDropDown
        function PlotDropDownValueChanged(app, event)
            value = app.PlotDropDown.Value;
            displayplots(app, value);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Average Spectrum')
            xlabel(app.UIAxes2, 'Wavelength (nm)')
            ylabel(app.UIAxes2, 'Reflectance')
            app.LeftButton = uibutton(app.UIFigure, 'push');
            app.LeftButton.ButtonPushedFcn = createCallbackFcn(app, @LeftButtonPushed, true);
            app.LeftButton.Text = '<';
            app.RightButton = uibutton(app.UIFigure, 'push');
            app.RightButton.ButtonPushedFcn = createCallbackFcn(app, @RightButtonPushed, true);
            app.RightButton.Text = '>';
            app.Status = uitextarea(app.UIFigure);
            app.Status.Value = {'Status'};
            app.TabGroup = uitabgroup(app.UIFigure);
            app.SpecimDataTab = uitab(app.TabGroup);
            app.SpecimDataTab.Title = 'Specim Data';
            app.StripROIPanel = uipanel(app.SpecimDataTab);
            app.StripROIPanel.Title = 'Strip ROI';
            app.PositionLabel = uilabel(app.StripROIPanel);
            app.PositionLabel.Text = 'Position';
            app.PositionSlider = uislider(app.StripROIPanel);
            app.PositionSlider.Limits = [0 1];
            app.PositionSlider.ValueChangedFcn = createCallbackFcn(app, @PositionSliderValueChanged, true);
            app.PositionSlider.Value = 0.5;
            app.VerticalCheckBox = uicheckbox(app.StripROIPanel);
            app.VerticalCheckBox.ValueChangedFcn = createCallbackFcn(app, @VerticalCheckBoxValueChanged, true);
            app.VerticalCheckBox.Text = 'Vertical';
            app.WidthSpinnerLabel = uilabel(app.StripROIPanel);
            app.WidthSpinnerLabel.Text = 'Width';
            app.WidthSpinner = uispinner(app.StripROIPanel);
            app.WidthSpinner.ValueChangedFcn = createCallbackFcn(app, @WidthSpinnerValueChanged, true);
            app.WidthSpinner.Value = 20;
            app.RegionofInterestPanel = uipanel(app.SpecimDataTab);
            app.RegionofInterestPanel.Title = 'Region of Interest';
            app.FullImageCheckBox = uicheckbox(app.RegionofInterestPanel);
            app.FullImageCheckBox.ValueChangedFcn = createCallbackFcn(app, @FullImageCheckBoxValueChanged, true);
            app.FullImageCheckBox.Text = 'Full Image';
            app.FullImageCheckBox.Value = true;
            app.EdgeFinderCheckBox = uicheckbox(app.RegionofInterestPanel);
            app.EdgeFinderCheckBox.ValueChangedFcn = createCallbackFcn(app, @EdgeFinderCheckBoxValueChanged, true);
            app.EdgeFinderCheckBox.Text = 'Edge Finder';
            app.StripCheckBox = uicheckbox(app.RegionofInterestPanel);
            app.StripCheckBox.ValueChangedFcn = createCallbackFcn(app, @StripCheckBoxValueChanged, true);
            app.StripCheckBox.Text = 'Strip';
            app.LoadSamplesButton = uibutton(app.SpecimDataTab, 'push');
            app.LoadSamplesButton.ButtonPushedFcn = createCallbackFcn(app, @LoadSamplesButtonPushed, true);
            app.LoadSamplesButton.Text = {'Load'; ''};
            app.ExportButton = uibutton(app.SpecimDataTab, 'push');
            app.ExportButton.ButtonPushedFcn = createCallbackFcn(app, @ExportButtonPushed, true);
            app.ExportButton.Text = 'Export';
            app.ViewSampleButton = uibutton(app.SpecimDataTab, 'push');
            app.ViewSampleButton.ButtonPushedFcn = createCallbackFcn(app, @ViewSampleButtonPushed, true);
            app.ViewSampleButton.Text = {'View Sample'; ''};
            app.CalibrationTab = uitab(app.TabGroup);
            app.CalibrationTab.Title = 'Calibration';
            app.XDataPanel = uipanel(app.CalibrationTab);
            app.XDataPanel.Title = 'X Data';
            app.ViewXButton = uibutton(app.XDataPanel, 'push');
            app.ViewXButton.ButtonPushedFcn = createCallbackFcn(app, @ViewXButtonPushed, true);
            app.ViewXButton.Text = 'View';
            app.LoadXButton = uibutton(app.XDataPanel, 'push');
            app.LoadXButton.ButtonPushedFcn = createCallbackFcn(app, @LoadXButtonPushed, true);
            app.LoadXButton.Text = 'Load';
            app.Label = uilabel(app.XDataPanel);
            app.Label.Text = '';
            app.DerivDegreeSpinner = uispinner(app.XDataPanel);
            app.DerivativeButton = uibutton(app.XDataPanel, 'push');
            app.DerivativeButton.ButtonPushedFcn = createCallbackFcn(app, @DerivativeButtonPushed, true);
            app.DerivativeButton.Text = 'Derivative';
            app.YDataPanel = uipanel(app.CalibrationTab);
            app.YDataPanel.Title = 'Y Data';
            app.LoadYButton = uibutton(app.YDataPanel, 'push');
            app.LoadYButton.ButtonPushedFcn = createCallbackFcn(app, @LoadYButtonPushed, true);
            app.LoadYButton.Text = 'Load';
            app.ViewYButton = uibutton(app.YDataPanel, 'push');
            app.ViewYButton.ButtonPushedFcn = createCallbackFcn(app, @ViewYButtonPushed, true);
            app.ViewYButton.Text = 'View';
            app.ModelCalibrationPanel = uipanel(app.CalibrationTab);
            app.ModelCalibrationPanel.Title = 'Model Calibration';
            app.ComponentsSpinnerLabel = uilabel(app.ModelCalibrationPanel);
            app.ComponentsSpinnerLabel.Text = 'Components';
            app.ComponentsSpinner = uispinner(app.ModelCalibrationPanel);
            app.ComponentsSpinner.Limits = [1 Inf];
            app.ComponentsSpinner.Value = 1;
            app.CVFoldsSpinnerLabel = uilabel(app.ModelCalibrationPanel);
            app.CVFoldsSpinnerLabel.Text = 'CV Folds';
            app.CVFoldsSpinner = uispinner(app.ModelCalibrationPanel);
            app.CVFoldsSpinner.Limits = [2 20];
            app.CVFoldsSpinner.Value = 2;
            app.CalibrateButton = uibutton(app.ModelCalibrationPanel, 'push');
            app.CalibrateButton.ButtonPushedFcn = createCallbackFcn(app, @CalibrateButtonPushed, true);
            app.CalibrateButton.Text = 'Calibrate';
            app.PlotDropDownLabel = uilabel(app.ModelCalibrationPanel);
            app.PlotDropDownLabel.Text = 'Plot:';
            app.PlotDropDown = uidropdown(app.ModelCalibrationPanel);
            app.PlotDropDown.Items = {'% Var Explained', 'Observed vs. Fitted', 'Residuals', 'MSE', 'X Data Weights'};
            app.PlotDropDown.ValueChangedFcn = createCallbackFcn(app, @PlotDropDownValueChanged, true);
            app.PlotDropDown.Value = '% Var Explained';
            app.UITable = uitable(app.ModelCalibrationPanel);
            app.UITable.ColumnName = {'N'; 'R2'; 'RMSEC'; 'RMSECV'};
            app.UITable.RowName = {''};
            app.UITable.ColumnSortable = true;
            app.PredictionTab = uitab(app.TabGroup);
            app.PredictionTab.Title = 'Prediction';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = hyperspectral_pls_system

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end